classdef Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Image2                          matlab.ui.control.Image
        EditField                       matlab.ui.control.NumericEditField
        TabGroup                        matlab.ui.container.TabGroup
        DirectoryselectionsTab          matlab.ui.container.Tab
        saveimage                       matlab.ui.control.Image
        ResultssavingLabel              matlab.ui.control.Label
        uploadimage                     matlab.ui.control.Image
        ImageuploadLabel                matlab.ui.control.Label
        SavingselectionTab              matlab.ui.container.Tab
        SurfacewithcontinuousfibersCheckBox  matlab.ui.control.CheckBox
        FibersContinuityCheckBox        matlab.ui.control.CheckBox
        FibersorientationCheckBox       matlab.ui.control.CheckBox
        FibersvectorfieldCheckBox       matlab.ui.control.CheckBox
        DetectedfibersCheckBox          matlab.ui.control.CheckBox
        TissueareaCheckBox              matlab.ui.control.CheckBox
        DataformatTab                   matlab.ui.container.Tab
        VectorfieldnumericdataDropDown  matlab.ui.control.DropDown
        VectorfieldnumericdataDropDownLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ContinueButton                  matlab.ui.control.Button
        Thresholdx100Slider             matlab.ui.control.Slider
        Thresholdx100SliderLabel        matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        UIAxes_6                        matlab.ui.control.UIAxes
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

%{
   Carriero Lab - City College of New York

FiberO to Quantify Fibers Orientation and Organization
FiberO is a software designed to quantify fibers orientation by performing morphological 
openings and automatically measure 
fibers organization by determining and plotting fiber continuity across the tissue

Conceptualized by: Alessandra Carriero
Written by: Asier Munoz, Anxhela Docaj, Julen Fernandez

LICENSE:
FiberO is a free software Licensed under the GNU Affero General Public License v3.0 License 
as published by the Free Software Foundation.
You may not use this file except in compliance with the License.
You may obtain a copy of the License in the FiberO folder or at

https://www.gnu.org/licenses/agpl-3.0.en.html 
 
Unless required by applicable law or agreed to in writing, software
distributed under this License is distributed on an "AS IS" BASIS,
WITHOUT WARRANTIES OR CONDITIONS OF ANY KIND, either express or implied.
See the License for the specific language governing permissions and
limitations under the License.

This section of the code defines the graphical interface for the FiberO software.
The interface allows users to upload images, adjust settings, and view fiber orientation 
analysis results. Components include image uploads, checkboxes for various analysis 
options, sliders for threshold adjustments, and axes for displaying results.

The interface is created using MATLAB's App Designer, where different 
properties (UI components) and methods (callback functions) are defined.

%}
    properties (Access = private)
        img0;                       % Original unprocessed image
        img1;                       % Image used for preprocessing
        img;                        % Preprocessed image used further steps
        imgo;                       % Image used for the calculation of tissue area
        selimg;                     % Copy of variable "img" used for visualization
        b_area;                     % Tissue area
        b_area_percent;             % Percentage of the image occupied by tissue
        x_y_region;                 % Created grid
        average;                    % Average intensity of each "x_y_region"
        average0;                   % Copy of variable "average" used for processing
        Selection;                  % User-selected regions for specific area removal
        h;                          % Contains the orientation value (in deg) of each "x_y_region"
        alignment;                  % Percentage of fibers continuity
        filename;                   % Name of the file being processed
        savepath;                   % Path to save the results
        maxAngles;                  % Angles calculated for each pixel based on fibers orientation
        CMap;                       % Colormap for visualizing fibers orientations
        Y;                          % Y values correspond to figures in the interface
        Y1;                         %
        Y2;                         %
        Y3;                         %
        Y4;                         %
        Y5;                         %
        Y6;                         %
        arrows;                     % Information for the generation of vector field
        Tr;                         % Threshold for tissue
        Trc;                        % Threshold for fibers
        removedback;                % Tissue image after background removal
        f_intensity;                % Image containing fibers after applying "Trc" threshold
        f_percent;                  % Percentage of the tissue occupied by fibers
        indexOfBiggest;             % Numbers of groups of fibers surfaces
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: uploadimage
        function uploadimageClicked(app, event)
            close all

            % Open a file selection dialog to choose an image file
            [app.filename, pathname] = uigetfile({'*.tif';'*.tiff';'*.jpg';'*.bmp';'*.gif';'*.png';}, 'File Selector');
            if pathname == 0
            	 % If the user cancels the file selection, exit the function
            	return;
            end
            fullpathname=strcat(pathname,app.filename);

            % Read the selected image
            app.img1 = imread(fullpathname);
            [~,app.filename,~]=fileparts(app.filename);
            app.Label.Text='Image uploaded';

            % Convert the image to 16-bit if it is in 8-bit format
            if isa(app.img1,'uint8')==1                                    
                app.img1=uint16(double(app.img1).*256);
            end

            cla(app.UIAxes_3)
            cla(app.UIAxes_4)
            cla(app.UIAxes_5)
            cla(app.UIAxes_6)

            % Set threshold values for tissue and fiber detection
            app.Tr=200;          
            app.Trc=app.Thresholdx100Slider.Value*100;

            % Preprocessing of the image for analysis
            [app.img, app.imgo, app.x_y_region, app.average, app.removedback, app.img0]=image_prep(app.img1,app.Tr);
            app.average0=app.average;
            
            % Visualization of tissue area
            fh1 = figure('Menu','none','ToolBar','none','visible','off');
            ax5 = axes('Units','Normalize','Position',[0 0 1 1]); ax5.Visible = 'off';
            imagesc(ax5,app.img0);
            colormap(ax5,'gray');
            ax6 = axes('Units','Normalize','Position',[0 0 1 1]); ax6.Visible = 'off';
            imagesc(ax6,app.average,'alphadata',app.average~=0,'CDataMapping', 'scaled');
            alpha(ax6,.2)
            cmap2 = [0 0 0; 1 0 0];
            colormap(ax6,cmap2); caxis([0 1.1]);
            ax6.Visible = 'off';
            linkprop([ax5 ax6],'Position');
            fh1.Position(3) = fh1.Position(4);                             
            [app.Y, ~]=frame2im(getframe(gcf));
            imagesc(app.UIAxes,app.Y)
            delete(fh1)

            % Visualization of detected fibers
            fh1 = figure('Menu','none','ToolBar','none','visible','off');
            ax5 = axes('Units','Normalize','Position',[0 0 1 1]); ax5.Visible = 'off';
            imagesc(ax5,app.imgo); 
            colormap(ax5,'gray'); 
            ax6 = axes('Units','Normalize','Position',[0 0 1 1]); ax6.Visible = 'off';
            imagesc(ax6,app.img>app.Trc,'alphadata',(app.img>app.Trc)~=0);
            alpha(ax6,.2)
            cmap2 = [0 0 0; 1 0.5 1];
            colormap(ax6,cmap2); caxis([0 1.1]);
            ax6.Visible = 'off';
            linkprop([ax5 ax6],'Position');
            fh1.Position(3) = fh1.Position(4);                              
            [app.Y1, ~]=frame2im(getframe(gcf));
            imagesc(app.UIAxes_2,app.Y1)
            delete(fh1)

            % Update the label to prompt the user for further action
            app.Label.Text='Change the selection? If not click continue';
            pathname=0;
            
        end

        % Value changed function: Thresholdx100Slider
        function Thresholdx100SliderValueChanged(app, event)
            % Update the label to notify that a new selection is being processed
            app.Label.Text='Processing new selection';

            % Update the threshold for fiber detection based on the slider value
            app.Trc=app.Thresholdx100Slider.Value*100;
            app.EditField.Value=app.Trc;

            % Preprocessing of the image for analysis
            [app.img, app.imgo, app.x_y_region, app.average, app.removedback, app.img0]=image_prep(app.img1,app.Tr);
            app.average0=app.average;   

            % Visualization of detected fibers
            fh1 = figure('Menu','none','ToolBar','none','visible','off');
            ax5 = axes('Units','Normalize','Position',[0 0 1 1]); ax5.Visible = 'off';
            imagesc(ax5,app.img0);
            colormap(ax5,'gray'); truesize
            ax6 = axes('Units','Normalize','Position',[0 0 1 1]); ax6.Visible = 'off';
            imagesc(ax6,app.img>app.Trc,'alphadata',(app.img>app.Trc)~=0,'CDataMapping', 'scaled');
            alpha(ax6,.2)
            cmap2 = [0 0 0; 1 0.5 1];
            colormap(ax6,cmap2); caxis([0 1.1]);
            ax6.Visible = 'off';
            linkprop([ax5 ax6],'Position');
            fh1.Position(3) = fh1.Position(4);                              
            [app.Y1, ~]=frame2im(getframe(gcf));
            imagesc(app.UIAxes_2,app.Y1)
            delete(gcf)

            % Update the label to prompt the user for further action
            app.Label.Text='New selection processed';
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            % Type a value to change the threshold
            app.Label.Text='Processing new selection';
            app.Trc=app.EditField.Value;
            app.Thresholdx100Slider.Value=app.Trc/100;
            [app.img, app.imgo, app.x_y_region, app.average, app.removedback, app.img0]=image_prep(app.img1,app.Tr);
            app.average0=app.average;  

            % Updated visualization of detected fibers
            fh1 = figure('Menu','none','ToolBar','none','visible','off');
            ax5 = axes('Units','Normalize','Position',[0 0 1 1]); ax5.Visible = 'off';
            imagesc(ax5,app.img0);
            colormap(ax5,'gray'); truesize
            ax6 = axes('Units','Normalize','Position',[0 0 1 1]); ax6.Visible = 'off';
            imagesc(ax6,app.img>app.Trc,'alphadata',(app.img>app.Trc)~=0,'CDataMapping', 'scaled');
            alpha(ax6,.2)
            cmap2 = [0 0 0; 1 0.5 1];
            colormap(ax6,cmap2); caxis([0 1.1]);
            ax6.Visible = 'off';
            linkprop([ax5 ax6],'Position');
            fh1.Position(3) = fh1.Position(4);                             
            [app.Y1, ~]=frame2im(getframe(gcf));
            imagesc(app.UIAxes_2,app.Y1)
            delete(gcf)
            app.Label.Text='New selection processed';
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            % User selection of regions to be removed
            [app.img, app.removedback, app.Selection]=removepoints(app.img, app.removedback, app.average);
            app.selimg=app.img;
            app.imgo=uint16(double(app.imgo).*app.Selection);
            app.imgo=imfill(app.imgo,"holes");

            % Initialization of variable for posterior visualization
            fh1 = figure('Menu','none','ToolBar','none','visible','off');
            ax5 = axes('Units','Normalize','Position',[0 0 1 1]); ax5.Visible = 'off';
            imagesc(ax5,app.img0);
            colormap(ax5,'gray');
            ax6 = axes('Units','Normalize','Position',[0 0 1 1]); ax6.Visible = 'off';
            imagesc(ax6,app.imgo,'alphadata',app.imgo~=0,'CDataMapping', 'scaled');
            alpha(ax6,.2)
            cmap2 = [0 0 0; 1 0 0];
            colormap(ax6,cmap2); caxis([0 1.1]);
            ax6.Visible = 'off';
            linkprop([ax5 ax6],'Position');
            fh1.Position(3) = fh1.Position(4);                              
            [app.Y, ~]=frame2im(getframe(gcf));
            imagesc(app.UIAxes,app.Y)
            delete(fh1)

            % Initialization of variable for posterior visualization
            fh1 = figure('Menu','none','ToolBar','none','visible','off');
            ax5 = axes('Units','Normalize','Position',[0 0 1 1]); ax5.Visible = 'off';
            imagesc(ax5,app.img0);
            colormap(ax5,'gray'); truesize
            ax6 = axes('Units','Normalize','Position',[0 0 1 1]); ax6.Visible = 'off';
            imagesc(ax6,app.img>app.Trc,'alphadata',(app.img>app.Trc)~=0,'CDataMapping', 'scaled');
            alpha(ax6,.2)
            cmap2 = [0 0 0; 1 0.5 1];
            colormap(ax6,cmap2); caxis([0 1.1]);
            ax6.Visible = 'off';
            linkprop([ax5 ax6],'Position');
            fh1.Position(3) = fh1.Position(4);                             
            [app.Y1, ~]=frame2im(getframe(gcf));
            imagesc(app.UIAxes_2,app.Y1)
            delete(gcf)

            % Calculation of tissue and fiber parameters
            app.b_area = sum(sum(app.imgo~=0));
            app.b_area_percent=(app.b_area/(size(app.imgo,1)*size(app.imgo,2)))*100;
            app.removedback=double(app.removedback).*app.Selection;
            app.f_percent=sum(sum(app.img>app.Trc)/app.b_area)*100;
            app.f_intensity=mean(mean(app.img(app.img>app.Trc)));
            
            % Variable dependent of image resolution
            if size(app.img,1)>=500
                mt = 1;
            end
            if size(app.img,1)>=1000
                mt = 2;
            end
            if size(app.img,1)>=2000
                mt = 4;
            end
            
            % Initialization of variables for orientation calculation
            app.maxAngles = uint16(zeros(size(app.img,1),'like',app.img));
            m = app.maxAngles;
            lengtLine = 35*mt;                                                                                            
            
            % Fibers orientation calculation
            for theta = 1 : 180                                                                                     
                app.Label.Text=['LOADING - Theta: ' num2str(theta) '/180'];
                pause(0.01)
                SE = strel('line', lengtLine, theta);
                openedImg = imopen(app.img, SE);
                idx = find(openedImg >= m);
                m(idx) = openedImg(idx);
                app.maxAngles(idx) = theta;
                if theta==1
                    m1=m;
                end
                if theta==180
                    m180=m;
                end
            end
            
            % Update the label to prompt the user for further action
            app.Label.Text='PLOTING...';
            pause(1)
            
            % Post-processing of orientation results
            app.maxAngles=double(app.removedback>0).*double(app.maxAngles);           
            idx=find(m1==m180);
            idy=find(m(idx)<10000);
            idz=idx(idy);
            app.maxAngles = double(app.maxAngles);
            app.maxAngles(idz)=NaN;

            % Colormap generation
            hex=['#A80090';'#123DC8';'#009FFF';'#00FFFF';...
                '#80FF80';'#EFFF10';'#FF8000';'#FF0000';'#A80090'];
            map = sscanf(hex','#%2x%2x%2x',[3,size(hex,1)]).' / 255;
            vec = [0;30/180*100;50/180*100;70/180*100;...
                90/180*100;110/180*100;130/180*100;150/180*100;100];
            N=128;
            app.CMap=interp1(vec,map,linspace(100,0,N),'pchip');
            c=[0 0 0;app.CMap];

            % Visualization of fibers orientation
            fh = figure('Menu','none','ToolBar','none','visible','off');
            ax3 = axes('Units','Normalize','Position',[0 0 1 1]); ax3.Visible = 'off';
            imagesc(ax3,app.removedback);
            colormap(ax3,'gray');
            ax4 = axes('Units','Normalize','Position',[0 0 1 1]);ax4.Visible = 'off';
            imagesc(ax4,app.maxAngles.*double(app.img>app.Trc)); truesize
            img2 = im2double(app.removedback);
            normImg2 = img2 - min(img2(:));
            normImg2 = normImg2 ./ max(normImg2(:));
            alpha(ax4,normImg2)
            colormap(ax4,c);
            ax4.Visible = 'off';
            linkprop([ax3 ax4],'Position');
            fh.Position(3) = fh.Position(4);
            [app.Y3, ~]=frame2im(getframe(gcf));
            imagesc(app.UIAxes_3,app.Y3)          
            delete(gcf)
             
            % Grid generation
            ngrid = 50;                                                      
            g = size(app.img,2)/ngrid;                                       
            posGrid = 0;
            app.x_y_region=[];
            for i = 1:ngrid                                                
                if (posGrid < size(app.img,1))
                    app.x_y_region(i,:) = [g*(i-1) g*i];
                else
                    break
                end
                posGrid = g*i;
            end
            app.x_y_region(1,1) = app.x_y_region(1,1) + 1;
            app.x_y_region = floor(app.x_y_region);

            % Calculation of fibers organization
            for i = 1:size(app.x_y_region,1)                                                                             
                for j = 1:size(app.x_y_region,1)
                    [l,F(i,j),C(i,j)] = mode(app.maxAngles(app.x_y_region(i,1):app.x_y_region(i,2), app.x_y_region(j,1):app.x_y_region(j,2)),[1 2]);
                    app.h(i,j) = l;
                end
            end
            idx=F<mean(mean(F(F>1)))-mean(mean(F(F>1)))*0.4;
            app.h(idx)=NaN;
            for i=1:size(app.x_y_region,1)
                for j=1:size(app.x_y_region,1)
                    app.average(i,j)=mean(mean(app.img(app.x_y_region(i,1):app.x_y_region(i,2),app.x_y_region(j,1):app.x_y_region(j,2))));
                    if app.average(i,j)<app.Trc-220
                        app.h(i,j)=NaN;
                    end
                end
            end

            imagesc(app.UIAxes_4,app.removedback);
            colormap(app.UIAxes_4,gray); 
            hold(app.UIAxes_4, "on"); 

            % Calculation of orientation vectors
            l = 4*mt;                                                                                                   
            for i = 1:size(app.x_y_region,1)                                                                            
                for j = 1:size(app.x_y_region,1)
                    % Midpoint of a voxel in the x axis
                    xc = j*size(app.img)/ngrid - size(app.img)/ngrid/2;                 
                    x2(i,j)=xc(1,1)+(l*cos(double(-app.h(i,j)*pi/180)));
                    x1(i,j)=((xc(1,1)-(l*cos(double(-app.h(i,j)*pi/180))))-x2(i,j));

                    % Midpoint of a voxel in the y axis
                    yc = i*size(app.img)/ngrid - size(app.img)/ngrid/2;                 
                    y2(i,j)=yc(1,1)+(l*sin(double(-app.h(i,j)*pi/180)));
                    y1(i,j)=((yc(1,1)-(l*sin(double(-app.h(i,j)*pi/180))))-y2(i,j));
                    if x1(i,j)<0
                        x2(i,j)=x2(i,j)+x1(i,j);
                        x1(i,j)=x1(i,j)*(-1);
                        y2(i,j)=y2(i,j)+y1(i,j);
                        y1(i,j)=y1(i,j)*(-1);
                    end
                end
            end

            % Visualization of vector field
            app.arrows=quiver(app.UIAxes_4,x2,y2,x1,y1,0,'Color','g'); 

            % Calculation of tissue organization parameters
            [B,C, cont, app.indexOfBiggest]=fibers_calc(app.img,app.h,app.Selection,app.Trc);
            C=C.*imresize(app.average0,size(app.img)/size(app.average0),'box');
            B=B.*imresize(app.average0,size(app.img)/size(app.average0),'box');
            app.alignment = (numel(find(B>0))/app.b_area)*100;

            % Visualization of tissue surfaces with continuous fibers
            fh = figure('Menu','none','ToolBar','none','visible','off');
            ax1 = axes('Units','Normalize','Position',[0 0 1 1]); ax1.Visible = 'off';
            imagesc(ax1,app.removedback);
            colormap(ax1,'gray'); axis square
            ax2 = axes('Units','Normalize','Position',[0 0 1 1]); ax2.Visible = 'off';
            imagesc(ax2,C,'alphadata',C~=0,'CDataMapping', 'scaled'); axis square
            alpha(ax2,.25)
            cmap = [0 0 0; 0 0 0];
            clrs = [1 0 1; 1 1 0; 1 0 0; 0 1 0; 0 0 1];
            for i = 1:cont-1.1
                if i <= 5
                    cmap = [cmap; clrs(i,:)];
                else
                    cmap = [cmap; rand(3)];
                end
            end
            colormap(ax2,cmap); caxis([0 cont]);
            ax2.Visible = 'off';
            linkprop([ax1 ax2],'Position');
            B(B>1)=1;
            fh.Position(3) = fh.Position(4);
            [app.Y5, ~]=frame2im(getframe(gcf));
            imagesc(app.UIAxes_5,app.Y5)          
            delete(gcf)

            % Visualization of fibers continuity
            fh = figure('Menu','none','ToolBar','none','visible','off');
            ax3 = axes('Units','Normalize','Position',[0 0 1 1]); ax3.Visible = 'off';
            imagesc(ax3,app.removedback);
            colormap(ax3,'gray'); truesize
            ax4 = axes('Units','Normalize','Position',[0 0 1 1]); ax4.Visible = 'off';
            imagesc(ax4,B,'alphadata',B~=0,'CDataMapping', 'scaled'); truesize
            alpha(ax4,.25)
            cmap2 = [0 0 0; 0 1 1];
            colormap(ax4,cmap2); caxis([0 1.1]);
            ax4.Visible = 'off';
            linkprop([ax3 ax4],'Position');
            fh.Position(3) = fh.Position(4);                               
            [app.Y6, ~]=frame2im(getframe(gcf));
            imagesc(app.UIAxes_6,app.Y6)
            delete(gcf)

            % Update the label to prompt the user for further action
            app.Label.Text='OPERATION FINISHED';
        end

        % Image clicked function: Image
        function Homeimage(app, event)
            Main;
            delete(app)
        end

        % Image clicked function: saveimage
        function saveimageClicked(app, event)
            % Saving of results depending on checked boxes at the "saving selection" tab
            app.savepath=uigetdir;
            if app.savepath == 0
                % User clicked the Cancel button.
                return;
            end
            
            mkdir([app.savepath '\' app.filename]);
            app.savepath=[app.savepath '\' app.filename];
            
            cont=0;
            if app.TissueareaCheckBox.Value==1
                cont=cont+1;
            end
            if app.DetectedfibersCheckBox.Value==1
                cont=cont+1;
            end
            if app.FibersorientationCheckBox.Value==1
                cont=cont+1;
            end
            if app.FibersvectorfieldCheckBox.Value==1
                cont=cont+1;
            end
            if app.SurfacewithcontinuousfibersCheckBox.Value==1
                cont=cont+1;
            end
            if app.FibersContinuityCheckBox.Value==1
                cont=cont+1;
            end
            
            conn=0;
            app.Label.Text=['Saving results... ' num2str(conn) '/' num2str(cont)];
            if app.TissueareaCheckBox.Value==1
                fh = figure('Menu','none','ToolBar','none','visible','off');
                ax = axes('Units','Normalize','Position',[0 0 1 1]); ax.Visible = 'off';
                imagesc(ax,app.Y); truesize; axis(ax,'square');
                fh.Position(3) = fh.Position(4);
                colormap(ax,gray)
                ax.Visible = 'off';
                saveas(fh,[app.savepath '\' app.filename '_1detectedtissue.png'])
                conn=conn+1;
                delete(gcf)
            end
            app.Label.Text=['Saving results... ' num2str(conn) '/' num2str(cont)];
            if app.DetectedfibersCheckBox.Value==1
                fh = figure('Menu','none','ToolBar','none','visible','off');
                ax = axes('Units','Normalize','Position',[0 0 1 1]); ax.Visible = 'off';
                imagesc(ax,app.Y1); truesize; axis(ax,'square');
                fh.Position(3) = fh.Position(4);
                ax.Visible = 'off';
                saveas(fh,[app.savepath '\' app.filename '_2fibers.png'])
                conn=conn+1;
                delete(gcf)
            end
            app.Label.Text=['Saving results... ' num2str(conn) '/' num2str(cont)];
            if app.FibersorientationCheckBox.Value==1
                fh = figure('Menu','none','ToolBar','none','visible','off');
                ax = axes('Units','Normalize','Position',[0 0 1 1]); ax.Visible = 'off';
                imagesc(ax,app.Y3); truesize; axis(ax,'square');
                fh.Position(3) = fh.Position(4);
                ax.Visible = 'off';
                c=[0 0 0;app.CMap];
                colormap(ax,c)
                saveas(fh,[app.savepath '\' app.filename '_3fiberorientation.png'])
                conn=conn+1;
                delete(gcf)
            end
            app.Label.Text=['Saving results... ' num2str(conn) '/' num2str(cont)];
            if app.FibersvectorfieldCheckBox.Value==1
                fh = figure('Menu','none','ToolBar','none','visible','off');
                ax = axes('Units','Normalize','Position',[0 0 1 1]); ax.Visible = 'off';
                imagesc(ax,app.removedback); hold(ax,'on'); truesize; axis(ax,'square');
                colormap(ax,gray)
                fh.Position(3) = fh.Position(4);
                ax.Visible = 'off';
                quiver(ax,app.arrows.XData,app.arrows.YData,app.arrows.UData,app.arrows.VData,0,'Color','g')
                saveas(fh,[app.savepath '\' app.filename '_4vectorfield.png'],'tiff')
                conn=conn+1;
                delete(gcf)
            end
            app.Label.Text=['Saving results... ' num2str(conn) '/' num2str(cont)];
            if app.SurfacewithcontinuousfibersCheckBox.Value==1
                fh = figure('Menu','none','ToolBar','none','visible','off');
                ax = axes('Units','Normalize','Position',[0 0 1 1]); ax.Visible = 'off';
                imagesc(ax,app.Y5); truesize; axis(ax,'square');
                fh.Position(3) = fh.Position(4);
                ax.Visible = 'off';
                saveas(fh,[app.savepath '\' app.filename '_5surfaces.png'])
                conn=conn+1;
                delete(gcf)
            end
            app.Label.Text=['Saving results... ' num2str(conn) '/' num2str(cont)];
            if app.FibersContinuityCheckBox.Value==1
                fh = figure('Menu','none','ToolBar','none','visible','off');
                ax = axes('Units','Normalize','Position',[0 0 1 1]); ax.Visible = 'off';
                imagesc(ax,app.Y6); truesize; axis(ax,'square');
                fh.Position(3) = fh.Position(4);
                ax.Visible = 'off';
                saveas(fh,[app.savepath '\' app.filename '_6fibercontinuity.png'])
                conn=conn+1;
                delete(gcf)
            end
            
            fh = figure('Menu','none','ToolBar','none','visible','off');
            ax = axes('Units','Normalize','Position',[0 0 1 1]); ax.Visible = 'off';
            imagesc(ax,app.img0); truesize; axis(ax,'square');
            fh.Position(3) = fh.Position(4);
            colormap(ax,gray)
            ax.Visible = 'off';
            saveas(fh,[app.savepath '\' app.filename '_0originalimage.png'])
            delete(gcf)

            fh = figure('Menu','none','ToolBar','none','visible','off');
            ax = axes('Units','Normalize','Position',[0 0 1 1]); ax.Visible = 'off';
            imagesc(ax,app.selimg); truesize; axis(ax,'square');
            fh.Position(3) = fh.Position(4);
            colormap(ax,gray)
            ax.Visible = 'off';
            saveas(fh,[app.savepath '\' app.filename '_analyzedimage.png'])
            delete(gcf)
            
            h=app.h;
            ext=app.VectorfieldnumericdataDropDown.Value;

            switch ext
                case '.mat'
                    save([app.savepath '\' 'Vector field numeric data.mat'], 'h');

                case '.xlsx'
                    writematrix(h, [app.savepath '\' 'Vector field numeric data.xlsx']);

                case '.csv'
                    writematrix(h, [app.savepath '\' 'Vector field numeric data.csv']);

                case '.txt'
                    writematrix(h, [app.savepath '\' 'Vector field numeric data.txt'], 'Delimiter', 'tab');
            end

            file=fopen([app.savepath '\' app.filename '_numericalresults.txt'],'wt');
            title=convertCharsToStrings(['Numerical results for ' app.filename]);
            text0=convertCharsToStrings(['Selected Threshold:  ' num2str(app.Trc)]);
            text1=convertCharsToStrings(['Percentage of tissue in the image:  ' num2str(app.b_area_percent) '%']);
            text2=convertCharsToStrings(['Percentage of detected Fibers in the tissue:  ' num2str(round(app.f_percent,2)) '%' '  with an average intensity of ' num2str(round(app.f_intensity,2))]);
            text3=convertCharsToStrings(['Continuous Fibers Surface:  ' num2str(size(app.indexOfBiggest,2)) ' groups of fibers']);
            text4=convertCharsToStrings(['Fiber Continuity:  ' num2str(round(app.alignment,2)) '%']);
            fprintf(file,'%s \n\n',title,text0,text1,text2,text3,text4);
            fclose(file);
            app.Label.Text=['Results saved - ' num2str(conn) '/' num2str(cont)];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Tissue area')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Detected Fibers')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.ZTick = [];
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Fibers orientation')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_3.ZTick = [];
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Fibers vector field')
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.UIAxes_4.ZTick = [];
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'Tissue surfaces with continuous fibers')
            app.UIAxes_5.XTick = [];
            app.UIAxes_5.YTick = [];
            app.UIAxes_5.ZTick = [];
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, 'Fibers continuity')
            app.UIAxes_6.XTick = [];
            app.UIAxes_6.YTick = [];
            app.UIAxes_6.ZTick = [];
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @Homeimage, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'house.png');
            app.Thresholdx100SliderLabel = uilabel(app.UIFigure);
            app.Thresholdx100SliderLabel.Text = 'Threshold (x100)';
            app.Thresholdx100Slider = uislider(app.UIFigure);
            app.Thresholdx100Slider.Limits = [2 14];
            app.Thresholdx100Slider.MajorTicks = [2 4 6 8 10 12 14 16];
            app.Thresholdx100Slider.ValueChangedFcn = createCallbackFcn(app, @Thresholdx100SliderValueChanged, true);
            app.Thresholdx100Slider.MinorTicks = [2 2.5 3 3.5 4 4.5 5 5.5 6 6.5 7 7.5 8 8.5 9 9.5 10 10.5 11 11.5 12 12.5 13 13.5 14 14.5 15 15.5 16];
            app.Thresholdx100Slider.Value = 7.5;
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = ' ';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DirectoryselectionsTab = uitab(app.TabGroup);
            app.DirectoryselectionsTab.Title = 'Directory selections';
            app.ImageuploadLabel = uilabel(app.DirectoryselectionsTab);
            app.ImageuploadLabel.Text = 'Image upload:';
            app.uploadimage = uiimage(app.DirectoryselectionsTab);
            app.uploadimage.ImageClickedFcn = createCallbackFcn(app, @uploadimageClicked, true);
            app.uploadimage.ImageSource = fullfile(pathToMLAPP, 'Import_image.png');
            app.ResultssavingLabel = uilabel(app.DirectoryselectionsTab);
            app.ResultssavingLabel.Text = 'Results saving:';
            app.saveimage = uiimage(app.DirectoryselectionsTab);
            app.saveimage.ImageClickedFcn = createCallbackFcn(app, @saveimageClicked, true);
            app.saveimage.ImageSource = fullfile(pathToMLAPP, 'Saving_image.png');
            app.SavingselectionTab = uitab(app.TabGroup);
            app.SavingselectionTab.Title = 'Saving selection';
            app.TissueareaCheckBox = uicheckbox(app.SavingselectionTab);
            app.TissueareaCheckBox.Text = 'Tissue area';
            app.TissueareaCheckBox.Value = true;
            app.DetectedfibersCheckBox = uicheckbox(app.SavingselectionTab);
            app.DetectedfibersCheckBox.Text = 'Detected fibers';
            app.DetectedfibersCheckBox.Value = true;
            app.FibersvectorfieldCheckBox = uicheckbox(app.SavingselectionTab);
            app.FibersvectorfieldCheckBox.Text = 'Fibers vector field';
            app.FibersvectorfieldCheckBox.Value = true;
            app.FibersorientationCheckBox = uicheckbox(app.SavingselectionTab);
            app.FibersorientationCheckBox.Text = 'Fibers orientation';
            app.FibersorientationCheckBox.Value = true;
            app.FibersContinuityCheckBox = uicheckbox(app.SavingselectionTab);
            app.FibersContinuityCheckBox.Text = 'Fibers Continuity';
            app.FibersContinuityCheckBox.Value = true;
            app.SurfacewithcontinuousfibersCheckBox = uicheckbox(app.SavingselectionTab);
            app.SurfacewithcontinuousfibersCheckBox.Text = 'Surface with continuous fibers';
            app.SurfacewithcontinuousfibersCheckBox.Value = true;
            app.DataformatTab = uitab(app.TabGroup);
            app.DataformatTab.Title = 'Data format';
            app.VectorfieldnumericdataDropDownLabel = uilabel(app.DataformatTab);
            app.VectorfieldnumericdataDropDownLabel.Text = 'Vector field numeric data  ';
            app.VectorfieldnumericdataDropDown = uidropdown(app.DataformatTab);
            app.VectorfieldnumericdataDropDown.Items = {'.mat', '.xlsx', '.csv', '.txt'};
            app.VectorfieldnumericdataDropDown.Value = '.mat';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 750;
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'colorbar.JPG');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end