classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        SponsoredbyLabel  matlab.ui.control.Label
        Image2            matlab.ui.control.Image
        Image_2           matlab.ui.control.Image
        WELCOMELabel      matlab.ui.control.Label
        CarrieroLabLabel  matlab.ui.control.Label
        Panel             matlab.ui.container.Panel
        Panel_2           matlab.ui.container.Panel
        DAnalysisButton   matlab.ui.control.Button
        Image             matlab.ui.control.Image
    end

    
    properties (Access = public)
%{
   Carriero Lab - City College of New York

FiberO to Quantify Fibers Orientation and Organization
FiberO is a software designed to quantify fibers orientation by performing morphological 
openings and automatically measure 
fibers organization by determining and plotting fiber continuity across the tissue

Conceptualized by: Alessandra Carriero
Written by: Asier Munoz, Anxhela Docaj, Julen Fernandez

LICENSE:
FiberO is a free software Licensed under the GNU Affero General Public License v3.0 License 
as published by the Free Software Foundation.
You may not use this file except in compliance with the License.
You may obtain a copy of the License in the FiberO folder or at

https://www.gnu.org/licenses/agpl-3.0.en.html 
 
Unless required by applicable law or agreed to in writing, software
distributed under this License is distributed on an "AS IS" BASIS,
WITHOUT WARRANTIES OR CONDITIONS OF ANY KIND, either express or implied.
See the License for the specific language governing permissions and
limitations under the License.

%}
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DAnalysisButton
        function DAnalysisButtonPushed(app, event)
            
            Interface;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Logo_original-removebg-preview.png');
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = ' ';
            app.DAnalysisButton = uibutton(app.Panel_2, 'push');
            app.DAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @DAnalysisButtonPushed, true);
            app.DAnalysisButton.Text = '2D Analysis';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = ' ';
            app.CarrieroLabLabel = uilabel(app.UIFigure);
            app.CarrieroLabLabel.Text = 'Carriero Lab';
            app.WELCOMELabel = uilabel(app.UIFigure);
            app.WELCOMELabel.Text = 'WELCOME';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'National-Science-Foundation-logo.jpg');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'logo_fiberO.JPG');
            app.SponsoredbyLabel = uilabel(app.UIFigure);
            app.SponsoredbyLabel.Text = 'Sponsored by';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end